classdef Crawler_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button_4         matlab.ui.control.StateButton
        Button_3         matlab.ui.control.Button
        DropDown         matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        Lamp             matlab.ui.control.Lamp
        Button_2         matlab.ui.control.StateButton
        DropDown_2       matlab.ui.control.DropDown
        DropDown_2Label  matlab.ui.control.Label
        Switch           matlab.ui.control.Switch
        Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Timer_1; % 计时器1，用来更新COM口
        Timer_2; % 计时器2，用来管理收发
        current_com; %当前选择的COM口
        Serial;
        Serial_Link_FLAG = false; %连接串口成功标志
        receiveData=[];
        Control_statue;%处于遥控器控制还是程序自动控制
    end
    methods(Access=private)

        function updateSerialList(app)
            %把可用COM口添加到下拉列表里
            COM_PORTs = serialportlist;
            app.DropDown_2.Items = COM_PORTs;
        end
        function receiveDataFromSerialPort(app,src,~)
            % 从串口接收数据
            app.receiveData = read(app.Serial,app.Serial.NumBytesAvailable,'char');%数据转化为ASCII码
        end
        function sendDataToSerialPort(app, data)
            % 向串口发送数据
            wrtieline(app.Serial, data);
        end
        function statue = SendCommand(app,command,expectedResponse)
            
            for i = 1:3  % 最多重复3次
                % 发送指令到串口
                writeline(app.Serial, command);
                pause(0.1) %延时10ms，给单片机反应时间
                % 设置超时时间（秒）
                timeout = 0.3;  % 60毫秒延迟        
                % 开始计时
                startTime = tic;
                % 等待串口返回消息
                while toc(startTime) < timeout
                        % 检查是否收到期望的回复消息
                 if app.Serial.NumBytesAvailable>0  %如果有可以读取的信息
                     app.receiveDataFromSerialPort(app)
                    if strcmp(app.receiveData, expectedResponse)
                        %已经成功收到答复
                        statue = true; %成功发送了指令，状态为1
                        return;
                    end
                 end   
                end
                statue = false;  %默认失败，如果成功会更改值
            end
            

        end
        function text_write(app,text)
            %用来向界面写入文字
            if isempty(app.TextArea.Value) %如果是空的就直接赋值
                app.TextArea.Value = text;
            else %不是就往后写
                app.TextArea.Value = [app.TextArea.Value;text];
            end
        end
        function CloseCom(app)
            %用来关闭串口
            %fclose(app.Serial);
            delete(app.Serial);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %不断读取可用的COM口
            app.Timer_1 = timer("ExecutionMode","fixedRate",'Period', ...
                2,'TimerFcn',@(src, event)updateSerialList(app));
            start(app.Timer_1);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.Button_2.Value = 0;
            app.Lamp.Color = [1.00,0.00,0.00];
            app.Lamp.Enable = "off";
            CloseCom(app);  %切换串口时关闭原来串口

            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            Button2_value = app.Button_2.Value;
                if Button2_value == 1 %按钮激活
                    %打开串口
                   if ~isempty(app.DropDown_2.Items) %检测是否为无可用COM口
                    try
                        app.Serial = serialport('COM5', 9600, "DataBits", 8, "StopBits", 1);
                        configureTerminator(app.Serial, "LF");  %终止符设置为换行符
                        %configureCallback(app.Serial,'byte',1,@app.receiveDataFromSerialPort); %收到信息时立即调用接收信息函数
                        fopen(app.Serial);
                    catch ME
                        text_write(app,ME.message); %报告错误
                        app.Button_2.Value = 0;      %返回之前未开启状态
                        return;                     %退出
                    end
                   end
                    try
                        if strcmp(app.Serial.Status,'open') %串口打开成功 
                            app.Lamp.Color = [0.00,1.00,0.00];
                            app.Lamp.Enable = "on";
                            text_write(app,"串口打开成功");
                            app.Serial_Link_FLAG = true;  %串口连接中
                        else  %串口打开失败
                            app.Lamp.Color = [1.00,0.00,0.00];
                            app.Lamp.Enable = "off";
                            app.Serial_Link_FLAG = false;%串口已断开
                            %这里报告串口打开失败
                            text_write(app,"串口打开失败");
                        end
                    catch ME
                        app.Lamp.Color = [1.00,0.00,0.00];
                        app.Lamp.Enable = "off";
                        %这里打印错误报告
                        text_write(app,"串口打开失败");
                        text_write(app,ME.message);

                        app.Serial_Link_FLAG = false;    %串口已断开
                    end
                elseif Button2_value == 0 %按钮关闭
                    %关闭串口
                    app.Lamp.Color = [1.00,0.00,0.00];
                    app.Lamp.Enable = "off";
                    CloseCom(app);
                    text_write(app,"串口已关闭");
                    app.Serial_Link_FLAG = false;       %串口已断开
                end
             
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
           if app.Serial_Link_FLAG == false
               text_write(app,"没有可用串口");
               return;
           end
           if app.Switch.Value == "On"
            if app.DropDown.Value == "泊车点"
                command = "Go to A";
                expectedResponse = "Gopark";       
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往泊车点");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点A"
                command = "Go to B";
                expectedResponse = "GoStop1";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往停车点A");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            elseif app.DropDown.Value == "停车点B"
                command = "Go to C";
                expectedResponse = "Gostop2";         %%%这里可能要改成16进制
                Statue = SendCommand(app,command,expectedResponse);
                if Statue
                        text_write(app,"正在前往停车点B");
                    else
                         text_write(app,"履带车无应答 未能成功设置目的地"); 
                         app.Switch.Value = 'Off'; %把开关状态改回Off
                end
            end
           end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.Control_statue = app.Switch.Value;
            if app.Serial_Link_FLAG == true
                if app.Control_statue == "On"
                    command = "87654321";
                    expectedResponse = "SelfControl";     
                    Statue = SendCommand(app,command,expectedResponse);
                    if Statue
                        text_write(app,"成功进入自动控制模式");
                    else
                         text_write(app,"履带车无应答 未能成功进入自动控制模式"); 
                    end
                    
                %进入遥控控制程序    
                elseif app.Control_statue == "Off"
                    command = "12345678";
                    expectedResponse = "RemoteControl";   
                    Statue = SendCommand(app,command,expectedResponse);
                     if Statue
                        text_write(app,"成功进入遥控控制模式");
                    else
                         text_write(app,"履带车无应答 未能成功进入遥控控制模式"); 
                     end
                    
                end
            else %如果没有可以用的串口
                text_write(app,"没有可用串口");
                if app.Switch.Value == "On"        %把开关拨回去
                    app.Switch.Value = 'Off';
                else
                    app.Switch.Value = 'On';
                end
            end    
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %关闭程序时暂且先只关闭串口，有什么要求可以再改
            CloseCom(app);
            delete(app)
            
        end

        % Drop down opening function: DropDown_2
        function DropDown_2Opening(app, event)
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            %清空文本显示
            app.TextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '自动控制';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '可用串口';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_2Opening, true);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '打开串口';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '目的地';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'泊车点', '停车点A', '停车点B'};
            app.DropDown.Value = '泊车点';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '出发';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '清空页面';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crawler_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end